classdef lidarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Gauge                         matlab.ui.control.Gauge
        GaugeLabel                    matlab.ui.control.Label
        ReadDistancecmLabel           matlab.ui.control.Label
        CommandEditField              matlab.ui.control.EditField
        CommandEditFieldLabel         matlab.ui.control.Label
        SendCommandsLabel             matlab.ui.control.Label
        ConnecttoPICButton            matlab.ui.control.Button
        VisualizationButton           matlab.ui.control.Button
        PICConnectionStatusLamp       matlab.ui.control.Lamp
        PICConnectionStatusLampLabel  matlab.ui.control.Label
        SerialPortEditField           matlab.ui.control.EditField
        SerialPortEditFieldLabel      matlab.ui.control.Label
        BaudRateEditField             matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel        matlab.ui.control.Label
        LiDARMeasurementsWithPIC18F87K22Label  matlab.ui.control.Label
        SendButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % Serial port device
    end
    
    methods (Access = private)
        %This function will update the gauge with the distance measurements
        %received from the sensor
        function readFunc(app)
            try
                while isvalid(app.device)
                    % Read data from the serial port
                    data = readline(app.device);
                    
                    % Display the received data
                    disp(['Received data: ' data]);
                    
                    % Parse to get the distance
                    charArr = convertStringsToChars(data);
                    dist = charArr(3:end-2);
                    dist = str2double(dist);    % Distance numeric

                    % Update gauge
                    app.Gauge.Value = dist;
                    
                    % Pause for a short time to avoid high CPU usage
                    pause(0.1);
                end
            catch e
                % Handle any exceptions (e.g., if the user closes the figure or errors occur)
                disp(['Error: ' e.message]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttoPICButton
        function ConnecttoPICButtonPushed(app, event)
            baud = app.BaudRateEditField.Value;
            port = app.SerialPortEditField.Value;
            
            try
                % Attempt to connect
                app.device = serialport(port, baud);

                % Successful connection
                app.PICConnectionStatusLampLabel.Text = "Connection Successful!";
                app.PICConnectionStatusLamp.Color = 'g';    % Green lamp

            catch
                % Connection failed
                app.PICConnectionStatusLampLabel.Text = "Connection Failed!";
                app.PICConnectionStatusLamp.Color = 'r';    % Red lamp
            end


        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            % Get the command from the text field
            command = app.CommandEditField.Value;
            command = convertCharsToStrings(command); % Convert to string

            % Send the command
            writeline(app.device, command);

            % Call read function
            app.readFunc();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send ';
            app.LiDARMeasurementsWithPIC18F87K22Label = uilabel(app.UIFigure);
            app.LiDARMeasurementsWithPIC18F87K22Label.Text = 'LiDAR Measurements With PIC18F87K22';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 19200;
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.PICConnectionStatusLampLabel = uilabel(app.UIFigure);
            app.PICConnectionStatusLampLabel.Text = 'PIC Connection Status';
            app.PICConnectionStatusLamp = uilamp(app.UIFigure);
            app.VisualizationButton = uibutton(app.UIFigure, 'push');
            app.VisualizationButton.Text = 'Visualization';
            app.ConnecttoPICButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoPICButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoPICButtonPushed, true);
            app.ConnecttoPICButton.Text = 'Connect to PIC';
            app.SendCommandsLabel = uilabel(app.UIFigure);
            app.SendCommandsLabel.Text = 'Send Commands';
            app.CommandEditFieldLabel = uilabel(app.UIFigure);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.UIFigure, 'text');
            app.ReadDistancecmLabel = uilabel(app.UIFigure);
            app.ReadDistancecmLabel.Text = 'Read Distance (cm)';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 500];
            app.Gauge.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end